classdef Complete_TS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        AveragetimeresolutionEditField  matlab.ui.control.NumericEditField
        AveragetimeresolutionEditFieldLabel  matlab.ui.control.Label
        AverageButton                   matlab.ui.control.Button
        AveragedLabel                   matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        ProcessedLabel                  matlab.ui.control.Label
        RawLabel                        matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        TimeresolutionEditField         matlab.ui.control.NumericEditField
        ProcessButton                   matlab.ui.control.Button
        TimeresolutionEditFieldLabel    matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        FilenameEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        t % Description
        t2
        t3
        date
        data
        date_mu % Description
        data_mu % Description
        output_ave
        data_ave
        date_ave
    end
    
    methods (Access = private)
        
        function [data_min,date_min]=makeup_time_simple(app,data,date,resolution)

            minDate = min(date); 
            maxDate = max(date);
            minDate.Second=0;
            maxDate.Second=0;
            minDate.Minute=0;
            maxDate.Minute=0;
            maxDate.Hour=maxDate.Hour+1;
            Con_date=minDate:minutes(resolution):maxDate;

            Con_date=Con_date';

            for i=1:length(Con_date)-1
    
    
             data_min(i,:)=nanmean(data(date>=Con_date(i) & date<Con_date(i+1),:),1);


           end


             date_min=Con_date(1:end-1);
             
         end
        
        function data_all_out=gas_time_average_new(app,data_in,date,Time_resolution) 


             dt1=datenum(date(:,1));

             former_res_number=dt1(2)-dt1(1);
             time_interval=datevec(dt1(2)-dt1(1));

             former_res=(time_interval(4)*3600+time_interval(5)*60+time_interval(6))/60;

             before_start_time=datetime(datevec(dt1(1)));
             before_start_time.Hour=0;
             before_start_time.Minute=0;
             before_start_time.Second=0;
             before_start_time=datenum(before_start_time);

             rows_insert_1=(dt1(1)-before_start_time)/former_res_number;
             if rows_insert_1>0

             %    for i=2:round(rows_insert_1)
             %     dt1 = [dt1(1:i-1,1);before_start_time+(i-1)*former_res_number; dt1(i:end,1)];
                 dt_before_mu=before_start_time:former_res_number:dt1(1);
                 dt_before_mu=dt_before_mu';
                 if isequal(dt_before_mu(end),dt1(1))
                 dt1 = [dt_before_mu;dt1(2:end)];
                 else
                 dt1 = [dt_before_mu;dt1];   
                 end
                 data_in= [zeros(size(dt_before_mu,1),size(data_in,2))*nan; data_in];
      
      
             %    end
             end

             after_end_time=datetime(datevec(dt1(end)));
             after_end_time.Hour=0;
             after_end_time.Minute=0;
             after_end_time.Second=0;
             after_end_time= datenum(after_end_time)+1;

             after_end_time=after_end_time-former_res_number;

             rows_insert_2=(after_end_time-dt1(end))/former_res_number;
             if rows_insert_2>0
                 dt_after_mu=dt1(end):former_res_number:after_end_time;
                 dt_after_mu=dt_after_mu';
                 dt1=[dt1;dt_after_mu(2:end)];
                 data_in= [data_in;zeros(size(dt_after_mu,1)-1,size(data_in,2))*nan];

             end


             max_time=datetime(datevec(after_end_time));
             start_time=datetime(datevec(before_start_time));
             date_out_temp=start_time:minutes(Time_resolution):max_time;
             date_out=transpose(date_out_temp);


             a=Time_resolution/former_res;

             for i=1:size(date_out,1)  
                 for j=1:size(data_in,2)
                     if a*i<=size(data_in,1)
                         data_out(i,j)=nanmean(data_in((i-1)*a+1:a*i,j));
                     else
                         data_out(i,j)=data_in((i-1)*a+1);
                     end
                 end
             end

              data_all_out(:,1)=datenum(date_out);
              data_all_out(:,2:size(data_out,2)+1)=data_out;
             %  data_all_out(:,1)=datetime(datevec(data_all_out(:,1)));
             %  xlswrite('E:\tof_CIMS\basic analysis\define factor\Biomass burning\timeaverage.xlsx',data_out);
              end   
    
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.xlsx');
            if isnumeric(filename) % user clicked Cancel
                return
            end
            app.FilenameEditField.Value=[pathname,filename];
            % sheetNames=app.SheetsDropDown.Value;
    
            app.t=readtable(fullfile(pathname,filename),"Sheet",1);
            app.data=table2array(app.t(:,2:end));
            app.date=table2array(app.t(:,1));
            % app.dataTable = t;
            app.UITable.Data=app.t; 
            app.UITable.ColumnName = app.t.Properties.VariableNames;

            figure(app.UIFigure);
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            [app.data_mu,app.date_mu]=makeup_time_simple(app,app.data,app.date,app.TimeresolutionEditField.Value);
            app.t2=timetable(app.date_mu,app.data_mu);
            app.t2=timetable2table(app.t2);
            % app.date_mu=datetime(app.t2(:,1),'ConvertFrom','datenum');
            app.UITable2.Data=app.t2; 
            % app.UITable2.ColumnName = app.t2.Properties.VariableNames;
            figure(app.UIFigure);
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
            app.output_ave=gas_time_average_new(app,app.data_mu,app.date_mu,app.AveragetimeresolutionEditField.Value);
            app.date_ave=app.output_ave(:,1);
            app.data_ave=app.output_ave(:,2:end);
            app.date_ave=datetime(app.date_ave,'ConvertFrom','datenum');
            app.t3=timetable(app.date_ave,app.data_ave);
            app.t3=timetable2table(app.t3);
            app.UITable3.Data=app.t3; 
            % app.UITable2.ColumnName = app.t2.Properties.VariableNames;
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.TimeresolutionEditFieldLabel = uilabel(app.UIFigure);
            app.TimeresolutionEditFieldLabel.Text = 'Time resolution';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TimeresolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeresolutionEditField.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.RawLabel = uilabel(app.UIFigure);
            app.RawLabel.Text = 'Raw';
            app.ProcessedLabel = uilabel(app.UIFigure);
            app.ProcessedLabel.Text = 'Processed';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.AveragedLabel = uilabel(app.UIFigure);
            app.AveragedLabel.Text = 'Averaged';
            app.AverageButton = uibutton(app.UIFigure, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.AveragetimeresolutionEditFieldLabel = uilabel(app.UIFigure);
            app.AveragetimeresolutionEditFieldLabel.Text = 'Average time resolution';
            app.AveragetimeresolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragetimeresolutionEditField.Value = 5;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'head.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Complete_TS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end